classdef StackViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        Panel2       matlab.ui.container.Panel
        FrameSlider  matlab.ui.control.Slider
        FrameLabel   matlab.ui.control.Label
        PlayButton   matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        stack % Description
        cb % Description
    end
    
    methods (Access = private)
        
        function playStack(app)
            app.PlayButton.Enable = 0;
            app.FrameSlider.Enable = 0;
            cLim = [min(0, min(app.stack, [], 'all', 'omitnan')),...
                   max(app.stack, [], 'all', 'omitnan')];
            for frame = 1:size(app.stack, 3)
                imagesc(app.UIAxes, app.stack(:,:,frame)); 
                caxis(app.UIAxes, cLim);
%                    [min(app.stack, [], 'all', 'omitnan'),...
%                    max(app.stack, [], 'all', 'omitnan')]);
                drawnow;
                app.FrameSlider.Value = frame;
            end
            app.PlayButton.Enable = 1;
            app.FrameSlider.Enable = 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, stack)
            disp(size(stack));
            app.stack = stack;
            disp(['App Stack size = ', num2str(size(app.stack,3))]);
            imagesc(app.UIAxes, app.stack(:,:,1)); axis(app.UIAxes, "image");
            app.cb = colorbar(app.UIAxes);
            caxis(app.UIAxes,...
               [min(app.stack, [], 'all', 'omitnan'),...
               max(app.stack, [], 'all', 'omitnan')]);
            app.FrameSlider.Limits = [1,size(app.stack,3)];
            app.FrameSlider.Value = 1;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            playStack(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel2 = uipanel(app.GridLayout);
            app.Panel2.Title = 'Panel 2';
            app.PlayButton = uibutton(app.Panel2, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.FrameLabel = uilabel(app.Panel2);
            app.FrameLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.Panel2);
            app.FrameSlider.Limits = [1 100];
            app.FrameSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StackViewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end